classdef bvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        StatusText  matlab.ui.control.Label
        BrainTree   matlab.ui.container.CheckBoxTree
    end

    
    properties (Access = private)
        bvParams % Parameters saved by user, describes data locations
        annotationTree % Tree structure of numeric annotation values
        acronymTree % Tree structure of region acronyms
        fullNameTree % Tree structure of full region names
        annotationTable % Table of annotations and acronyms
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the path info, assuming it is saved in this folder
            appPath = mfilename('fullpath');
            bvPath = fileparts(fileparts(appPath));
            D = dir([bvPath,filesep,'**',filesep,'brainViewer_params.mat']);
            paramPath = fullfile(D.folder,D.name);
            if exist(paramPath,"file")
                app.bvParams = load(paramPath);
                app.StatusText.Text = 'Loading annotation...';
                drawnow;
                [~,app.annotationTable,app.acronymTree,app.annotationTree,app.fullNameTree] = ...
                    loadAllData_bv(app.bvParams,"annotation");
                app.StatusText.Text = 'Populating tree...';
                drawnow
                populateBrainTree(app,app.fullNameTree);
                app.StatusText.Text = 'Select Regions to Plot:';
            else
                errordlg("Could not find 'brainViewer_params' in the brainViewer folder.")
                app.delete;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BrainTree = uitree(app.GridLayout, 'checkbox');
            app.StatusText = uilabel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bvApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end